classdef ImgRecApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        TextArea2               matlab.ui.control.TextArea
        TextArea                matlab.ui.control.TextArea
        TextAreaLabel           matlab.ui.control.Label
        ChooseNNListBox         matlab.ui.control.ListBox
        ChooseoneoftheinstalledNeuralNetworksListBoxLabel  matlab.ui.control.Label
        ChooseWCListBox         matlab.ui.control.ListBox
        ChooseoneoftheavailableWebcamsListBoxLabel  matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton              matlab.ui.control.RadioButton
        ContinuousButton        matlab.ui.control.RadioButton
        SnapshotButton          matlab.ui.control.RadioButton
        ImageAcquisitionButton  matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Network
        ChosenWebcam
        webcamera
        cam
        nnet
        nnetindex
        stop
    end
    
    methods (Access = private) 
        function label = identify(app)
            if  app.ChosenWebcam == "mobiledev"
                picture = snapshot (app.cam, 'manual');
            else
                picture=snapshot(app.webcamera);
            end
            picture=imresize(picture, [app.nnet.Layers(1).InputSize(1), app.nnet.Layers(1).InputSize(2)]);
            label = classify(app.nnet, picture);
            imshow (picture, 'Parent', app.UIAxes);
            title(app.UIAxes, ['Classification: ' label])
        end
        
        function results = SetupError(app)
           if   or(app.nnetindex == 0, isempty(app.webcamera))
                app.ButtonGroup.Visible = false;
                app.ButtonGroup.Enable = false;
                app.ImageAcquisitionButton.Visible = false;
                app.ImageAcquisitionButton.Enable = false;
           else
                app.ImageAcquisitionButton.Visible = true;
                app.ImageAcquisitionButton.Enable = true;
           end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create the table of available networks, names and expected
            % picture size
            app.ChooseNNListBox.Items(1) = cellstr("Default Network");
            app.ChooseNNListBox.Items(2) = cellstr("alexnet");
            app.ChooseNNListBox.Items(3) = cellstr("vgg16");
            app.ChooseNNListBox.Items(4) = cellstr("vgg19");
            app.ChooseNNListBox.Items(5) = cellstr("googlenet");
            app.ChooseNNListBox.Items(6) = cellstr("resnet50");
            app.ChooseNNListBox.Items(7) = cellstr("squeezenet");
            app.ChooseNNListBox.Items(8) = cellstr("resnet18");
            app.ChooseNNListBox.Items(9) = cellstr("resnet101");

            % initialize ItemsData with the same table, but the first
            % element
            app.ChooseNNListBox.ItemsData = string(app.ChooseNNListBox.Items);
            % set the first element of the list to the default network
            app.ChooseNNListBox.ItemsData {1} = app.ChooseNNListBox.ItemsData {2}; 
            
            % create the table of available cameras
            app.ChooseWCListBox.Items = ["Default Webcam"; webcamlist];
            % Set the last element to manage acquisition through the smartphone
            app.ChooseWCListBox.Items {end+1}= 'mobiledev';
            % initialize ItemsData with the same table, but the first
            % element
            app.ChooseWCListBox.ItemsData = app.ChooseWCListBox.Items;
            % set the first element of the list to the default camera
            app.ChooseWCListBox.ItemsData{1} = app.ChooseWCListBox.Items{2};

            % Makes Listboxes visible and enabled
            app.ChooseWCListBox.Visible = true;
            app.ChooseWCListBox.Enable = true;
            app.ChooseNNListBox.Visible = true;
            app.ChooseNNListBox.Enable = true;
            app.ImageAcquisitionButton.Visible = false;
            app.ImageAcquisitionButton.Enable = false;
            % app.ChooseoneoftheavailableWebcamsListBoxLabel.Visible = 'on';
            % app.ChooseoneoftheinstalledNeuralNetworksListBoxLabel.Visible = 'on';

            app.nnetindex = 0;
            app.webcamera = [];
            app.TextArea2.Value = "";

        end

        % Value changed function: ChooseWCListBox
        function ChooseWCListBoxValueChanged(app, event)
            app.ChosenWebcam = app.ChooseWCListBox.ItemsData {app.ChooseWCListBox.ValueIndex};
            app.TextArea2.Value = "";

            if app.ChosenWebcam == "mobiledev"
                try
                    app.webcamera = []
                    app.webcamera = mobiledev;
                    app.cam = camera(app.webcamera, 'back');
                    app.cam.Autofocus = 'on';
                catch
                    app.TextArea2.Value = "Verificare che MATLAB Mobile sia in esecuzione sullo smartphone"
                    SetupError (app)
                    return
                end
            else
                app.webcamera=webcam(app.ChosenWebcam);
            end

% We need a selected camera and a selected network to perform the classification
            if app.nnetindex > 0
                app.SnapshotButton.Visible = true;
                app.SnapshotButton.Enable = true;
                app.ButtonGroup.Visible = true;
                app.ButtonGroup.Enable = true;
            end
            SetupError (app)
        end

        % Value changed function: ChooseNNListBox
        function ChooseNNListBoxValueChanged(app, event)
            app.nnet = eval(app.ChooseNNListBox.ItemsData (app.ChooseNNListBox.ValueIndex));
            app.nnetindex = app.ChooseNNListBox.ValueIndex;

% We need a selected camera and a selected network to perform the classification
%            if  app.webcamera == []
            if  isempty(app.webcamera)
            else
                app.SnapshotButton.Visible = true;
                app.SnapshotButton.Enable = true;
                app.ButtonGroup.Visible = true;
                app.ButtonGroup.Enable = true;
                app.SnapshotButton.Value = 1;
            end
            SetupError (app)
        end

        % Callback function: ButtonGroup, ButtonGroup,
        % ImageAcquisitionButton
        function ButtonGroupButtonDown(app, event)
            if app.SnapshotButton.Value == 1
                app.stop = "y";
                app.ImageAcquisitionButton.Visible = true;
                app.ImageAcquisitionButton.Enable = true;
                identify(app);
            elseif app.ContinuousButton.Value == 1
                app.stop = "n";
                app.ImageAcquisitionButton.Visible = false;
                app.ImageAcquisitionButton.Enable = false;
                while app.stop == "n"                    
                    identify(app);         
                    pause (0.1)
                end
            elseif app.NoneButton.Value == 1
                app.ImageAcquisitionButton.Visible = false;
                app.ImageAcquisitionButton.Enable = false;
                app.stop = "y";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {306, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ChooseoneoftheavailableWebcamsListBoxLabel = uilabel(app.LeftPanel);
            app.ChooseoneoftheavailableWebcamsListBoxLabel.Text = 'Choose one of the available Webcams';
            app.ChooseWCListBox = uilistbox(app.LeftPanel);
            app.ChooseWCListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseWCListBoxValueChanged, true);
            app.ChooseWCListBox.Value = {};
            app.ChooseoneoftheinstalledNeuralNetworksListBoxLabel = uilabel(app.LeftPanel);
            app.ChooseoneoftheinstalledNeuralNetworksListBoxLabel.Text = 'Choose one of the installed Neural Networks';
            app.ChooseNNListBox = uilistbox(app.LeftPanel);
            app.ChooseNNListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseNNListBoxValueChanged, true);
            app.ChooseNNListBox.Value = {};
            app.TextAreaLabel = uilabel(app.LeftPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'App based on:'; 'Deep Learning in MATLAB in 10 lines of code'; '(Joe Hicklin)'};
            app.TextArea2 = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.ImageAcquisitionButton = uibutton(app.RightPanel, 'push');
            app.ImageAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.ImageAcquisitionButton.Text = 'Image Acquisition';
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.ButtonGroup.Enable = 'off';
            app.ButtonGroup.Title = 'Button Group';
            app.ButtonGroup.Visible = 'off';
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.SnapshotButton = uiradiobutton(app.ButtonGroup);
            app.SnapshotButton.Text = 'Snapshot';
            app.SnapshotButton.Value = true;
            app.ContinuousButton = uiradiobutton(app.ButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.NoneButton = uiradiobutton(app.ButtonGroup);
            app.NoneButton.Text = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImgRecApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end